classdef LEDS_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EsclavoButton          matlab.ui.control.StateButton
        APPLICACIONDECONTROLDELEDSLabel  matlab.ui.control.Label
        DECIMALEditField       matlab.ui.control.NumericEditField
        DECIMALEditFieldLabel  matlab.ui.control.Label
        BINARYEditField        matlab.ui.control.NumericEditField
        BINARYEditFieldLabel   matlab.ui.control.Label
        Lamp_6                 matlab.ui.control.Lamp
        Lamp_5                 matlab.ui.control.Lamp
        Lamp_4                 matlab.ui.control.Lamp
        Lamp_3                 matlab.ui.control.Lamp
        Lamp_2                 matlab.ui.control.Lamp
        Lamp                   matlab.ui.control.Lamp
        EditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        a = arduino();
        pins = [2, 3, 4, 5, 6, 7, 8];
        recibido = 0;
         % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            for i = 1:1:6
                state = bitget(value, i);
                writeDigitalPin(app.a, "D"+app.pins(i), state);
     
      
            end
            response = thingSpeakWrite(1755520,value,'WriteKey','VU9QO1Y7269MEORN', 'Timeout', 500);

            
        end

        % Value changed function: BINARYEditField
        function BINARYEditFieldValueChanged(app, event)
            binary = app.BINARYEditField.Value;
            binstr = string(binary);
            decimal = bin2dec(binstr);
            app.DECIMALEditField.Value = decimal;
            
        end

        % Value changed function: EsclavoButton
        function EsclavoButtonValueChanged(app, event)
            while(app.EsclavoButton.Value ~=1)
                leds = thingSpeakRead(1755520, "ReadKey", "812J3T9193Wrr1S8");
                for i = 1:1:6
                      state = bitget(leds, i);
                      writeDigitalPin(app.a, "D"+app.pins(i), state); 
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.BINARYEditFieldLabel = uilabel(app.UIFigure);
            app.BINARYEditFieldLabel.Text = 'BINARY';
            app.BINARYEditField = uieditfield(app.UIFigure, 'numeric');
            app.BINARYEditField.ValueChangedFcn = createCallbackFcn(app, @BINARYEditFieldValueChanged, true);
            app.DECIMALEditFieldLabel = uilabel(app.UIFigure);
            app.DECIMALEditFieldLabel.Text = 'DECIMAL';
            app.DECIMALEditField = uieditfield(app.UIFigure, 'numeric');
            app.APPLICACIONDECONTROLDELEDSLabel = uilabel(app.UIFigure);
            app.APPLICACIONDECONTROLDELEDSLabel.Text = 'APPLICACION DE CONTROL DE LEDS';
            app.EsclavoButton = uibutton(app.UIFigure, 'state');
            app.EsclavoButton.ValueChangedFcn = createCallbackFcn(app, @EsclavoButtonValueChanged, true);
            app.EsclavoButton.Text = 'Esclavo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LEDS_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end